classdef SegmentLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        SegmentarButton               matlab.ui.control.Button
        ApellidodelpacienteLabel      matlab.ui.control.Label
        ApellidodelpacienteEditField  matlab.ui.control.EditField
        CargarEstudioButton           matlab.ui.control.Button
        MomentodelcicloDropDownLabel  matlab.ui.control.Label
        MomentodelcicloDropDown       matlab.ui.control.DropDown
    end


    properties (Access = public)
        Apellido % Description
        Study % Description
        Path = 'C:\Users\Vicky\OneDrive\Desktop\FACULTAD\TESIS\Codigo\SegmentosXprofesional'
      
    end

    properties (Access = public)
    end


    methods (Access = private)

        % Button pushed function: SegmentarButton
        function SegmentarButtonPushed(app, event)
            close all
            if(~isempty(app.Study))
                h = figure('Name','Segmentar')
                imshow(app.Study)
                H = imfreehand('Closed',1);
                p = wait(H)
                % create a mask and get coordinates
                mask = H.createMask();
                area= im2double(app.Study).*im2double(mask);
                N = size(p,1);
                p(N+1,:) = p(1,:) % Cierro el contorno
                if isequal(app.MomentodelcicloDropDown.Value,'Diástole')
                    save([app.Path strcat('placa_',app.Apellido,'_D.mat')] , 'p', 'area' )
                    msg = msgbox('SEGMENTO GUARDADO');
                    close(h)
                else 
                    save([app.Path strcat('/placa_',app.Apellido,'_S.mat')] , 'p', 'area')
                    msg = msgbox('SEGMENTO GUARDADO');
                    close(h)
                end
            else 
                f = errordlg('No hay ningun estudio cargado','ERROR');
            end
        end

        % Button pushed function: CargarEstudioButton
        function CargarEstudioButtonPushed(app, event)
            
            if(~isempty(app.Apellido))
                %app.Study = Read_image_and_apply_LUT(strcat(app.Apellido,'.jpg'));
                %app.Study = app.Study(:,:,:,50);
                
                if isequal(app.MomentodelcicloDropDown.Value,'Diástole')
                    app.Study = imread(strcat(app.Apellido,'_D.jpg'));
                else 
                    app.Study = imread(strcat(app.Apellido,'_S.jpg'));
                end
                app.Study = app.Study(71:522,267:654);
                imshow(app.Study,'Parent',app.UIAxes)
                
            else 
                f = errordlg('Elija un paciente por su apellido','ERROR');
            end
            
           
        end

        % Value changing function: ApellidodelpacienteEditField
        function ApellidodelpacienteEditFieldValueChanging(app, event)
            app.Apellido = event.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.SegmentarButton = uibutton(app.UIFigure, 'push');
            app.SegmentarButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentarButtonPushed, true);
            app.SegmentarButton.Text = 'Segmentar';
            app.ApellidodelpacienteLabel = uilabel(app.UIFigure);
            app.ApellidodelpacienteLabel.Text = {'Apellido del'; 'paciente'};
            app.ApellidodelpacienteEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidodelpacienteEditField.ValueChangingFcn = createCallbackFcn(app, @ApellidodelpacienteEditFieldValueChanging, true);
            app.CargarEstudioButton = uibutton(app.UIFigure, 'push');
            app.CargarEstudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEstudioButtonPushed, true);
            app.CargarEstudioButton.Text = 'Cargar Estudio';
            app.MomentodelcicloDropDownLabel = uilabel(app.UIFigure);
            app.MomentodelcicloDropDownLabel.Text = {'Momento '; 'del ciclo'};
            app.MomentodelcicloDropDown = uidropdown(app.UIFigure);
            app.MomentodelcicloDropDown.Items = {'Sístole', 'Diástole'};
            app.MomentodelcicloDropDown.Value = 'Sístole';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SegmentLab

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end